classdef AcousticParameterEvaluation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Header                        matlab.ui.control.Image
        AcousticalEvaluationLabel     matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        SpeechLabel                   matlab.ui.control.Label
        MusicLabel                    matlab.ui.control.Label
        NoiseLabel                    matlab.ui.control.Label
        SpeechListBox                 matlab.ui.control.ListBox
        MusicListBox                  matlab.ui.control.ListBox
        NoiseListBox                  matlab.ui.control.ListBox
        PlayAudioButton               matlab.ui.control.Button
        ViewElectrodogramButton       matlab.ui.control.Button
        PerformanceSummaryButton      matlab.ui.control.Button
        UserParameterEvalutaionLabel  matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        chchannelsLabel               matlab.ui.control.Label
        smicrosecondsLabel            matlab.ui.control.Label
        ppschpulsespersecondperchannelLabel  matlab.ui.control.Label
        NMaxListBox                   matlab.ui.control.ListBox
        PulseWidthListBox             matlab.ui.control.ListBox
        StimRateListBox               matlab.ui.control.ListBox
        nmaximaNoofselectedchannelsLabel  matlab.ui.control.Label
        PulseWidthLabel               matlab.ui.control.Label
        StimulationRateLabel          matlab.ui.control.Label
        RealizabilityTestButton       matlab.ui.control.Button
        ProcessedParametersButton     matlab.ui.control.Button
        PerformanceSummaryButton_2    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
% Insert code when Play Audio Button is pushed


% Assign value to 'GUI_Val'

app.PlayAudioButton.Value = GUI_Val;
        end

        % Button pushed function: ViewElectrodogramButton
        function ViewElectrodogramButtonPushed(app, event)
% Insert code when View Electrodogram Button is pushed


% Assign value to 'GUI_Val'

app.ViewElectrodogramButton.Value = GUI_Val;
        end

        % Button pushed function: PerformanceSummaryButton
        function PerformanceSummaryButtonPushed(app, event)
% Insert code when Performance Summary Button pushed on Audio Evaluation side


% Assign value to 'GUI_Val'

app.PerformanceSummaryButton.Value = GUI_Val;
        end

        % Button pushed function: RealizabilityTestButton
        function RealizabilityTestButtonPushed(app, event)
% Insert code when Realizability Test Button is pushed


% Assign value to 'GUI_Val'

app.RealizabilityTestButton.Value = GUI_Val;
        end

        % Button pushed function: ProcessedParametersButton
        function ProcessedParametersButtonPushed(app, event)
% Insert code when Processed Parameters Button is pushed


% Assign value to 'GUI_Val'

app.ProcessedParametersButton.Value = GUI_Val;
        end

        % Button pushed function: PerformanceSummaryButton_2
        function PerformanceSummaryButton_2Pushed(app, event)
% Insert code when Performance Summary Button pushed on User Parameter Evaluation side


% Assign value to 'GUI_Val'

app.PerformanceSummaryButton_2.Value = GUI_Val;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Header = uiimage(app.UIFigure);
            app.Header.ImageSource = 'size70black.png';
            app.AcousticalEvaluationLabel = uilabel(app.UIFigure);
            app.AcousticalEvaluationLabel.Text = 'Acoustical Evaluation';
            app.Panel = uipanel(app.UIFigure);
            app.SpeechLabel = uilabel(app.Panel);
            app.SpeechLabel.Text = 'Speech';
            app.MusicLabel = uilabel(app.Panel);
            app.MusicLabel.Text = 'Music';
            app.NoiseLabel = uilabel(app.Panel);
            app.NoiseLabel.Text = 'Noise';
            app.SpeechListBox = uilistbox(app.Panel);
            app.SpeechListBox.Items = {'Wav File 1', 'Wav File 2', 'Wav File 3', 'Wav File 4', 'Wav File 5'};
            app.SpeechListBox.Value = 'Wav File 1';
            app.MusicListBox = uilistbox(app.Panel);
            app.MusicListBox.Items = {'Wav File 1', 'Wav File 2', 'Wav File 3', 'Wav File 4', 'Wav File 5'};
            app.MusicListBox.Value = 'Wav File 1';
            app.NoiseListBox = uilistbox(app.Panel);
            app.NoiseListBox.Items = {'Wav File 1', 'Wav File 2', 'Wav File 3', 'Wav File 4', 'Wav File 5'};
            app.NoiseListBox.Value = 'Wav File 1';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.ViewElectrodogramButton = uibutton(app.UIFigure, 'push');
            app.ViewElectrodogramButton.ButtonPushedFcn = createCallbackFcn(app, @ViewElectrodogramButtonPushed, true);
            app.ViewElectrodogramButton.Text = {'View'; 'Electrodogram'};
            app.PerformanceSummaryButton = uibutton(app.UIFigure, 'push');
            app.PerformanceSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @PerformanceSummaryButtonPushed, true);
            app.PerformanceSummaryButton.Text = {'Performance'; 'Summary'};
            app.UserParameterEvalutaionLabel = uilabel(app.UIFigure);
            app.UserParameterEvalutaionLabel.Text = 'User Parameter Evalutaion';
            app.Panel_2 = uipanel(app.UIFigure);
            app.chchannelsLabel = uilabel(app.Panel_2);
            app.chchannelsLabel.Text = '(ch:channels)';
            app.smicrosecondsLabel = uilabel(app.Panel_2);
            app.smicrosecondsLabel.Text = '(μs: micro-seconds)';
            app.ppschpulsespersecondperchannelLabel = uilabel(app.Panel_2);
            app.ppschpulsespersecondperchannelLabel.Text = {'(pps/ch: pulses per'; ' second per channel)'};
            app.NMaxListBox = uilistbox(app.Panel_2);
            app.NMaxListBox.Items = {'8'};
            app.NMaxListBox.Value = {};
            app.PulseWidthListBox = uilistbox(app.Panel_2);
            app.PulseWidthListBox.Items = {'25'};
            app.PulseWidthListBox.Value = {};
            app.StimRateListBox = uilistbox(app.Panel_2);
            app.StimRateListBox.Items = {'1000'};
            app.StimRateListBox.Value = {};
            app.nmaximaNoofselectedchannelsLabel = uilabel(app.Panel_2);
            app.nmaximaNoofselectedchannelsLabel.Text = {'''n-maxima'''; '(No. of  selected channels)'};
            app.PulseWidthLabel = uilabel(app.Panel_2);
            app.PulseWidthLabel.Text = 'Pulse Width';
            app.StimulationRateLabel = uilabel(app.Panel_2);
            app.StimulationRateLabel.Text = 'Stimulation Rate';
            app.RealizabilityTestButton = uibutton(app.UIFigure, 'push');
            app.RealizabilityTestButton.ButtonPushedFcn = createCallbackFcn(app, @RealizabilityTestButtonPushed, true);
            app.RealizabilityTestButton.Text = {'Realizability '; 'Test'};
            app.ProcessedParametersButton = uibutton(app.UIFigure, 'push');
            app.ProcessedParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessedParametersButtonPushed, true);
            app.ProcessedParametersButton.Text = {'Processed'; 'Parameters'};
            app.PerformanceSummaryButton_2 = uibutton(app.UIFigure, 'push');
            app.PerformanceSummaryButton_2.ButtonPushedFcn = createCallbackFcn(app, @PerformanceSummaryButton_2Pushed, true);
            app.PerformanceSummaryButton_2.Text = {'Performance'; 'Summary'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcousticParameterEvaluation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end