classdef AudioFileProcesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioFileProcessorApp          matlab.ui.Figure
        LocationPanel                  matlab.ui.container.Panel
        LocationText                   matlab.ui.control.Label
        DirectoryPanel                 matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        SelectFolderButton             matlab.ui.control.Button
        CurrentfileLabel               matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        StreamFromListBoxPanel         matlab.ui.container.Panel
        PreviousFileButton             matlab.ui.control.Button
        SelectedFileButton             matlab.ui.control.Button
        NextFileButton                 matlab.ui.control.Button
        LeftGainPanel                  matlab.ui.container.Panel
        slider_lgain                   matlab.ui.control.Slider
        text25dB_lgain                 matlab.ui.control.Label
        text0dB_lgain                  matlab.ui.control.Label
        text50dB_lgain                 matlab.ui.control.Label
        RightGainPanel                 matlab.ui.container.Panel
        slider_rgain                   matlab.ui.control.Slider
        text25dB_rgain                 matlab.ui.control.Label
        text0dB_rgain                  matlab.ui.control.Label
        text50dB_rgain                 matlab.ui.control.Label
        MAPfileLoadPanel               matlab.ui.container.Panel
        LoadMAPfileText                matlab.ui.control.Label
        CCiMOBILECommunicationPanel    matlab.ui.container.Panel
        StreamingtoCCiMOBILELampLabel  matlab.ui.control.Label
        StreamingtoCCiMOBILELamp       matlab.ui.control.Lamp
        Header                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        tableIndex % index of selected item in table
        folderPath % path to current .wav files folder
    end
methods (Access = private)
    function disable_buttons(app)
        app.AudioFileProcessorApp.Interruptible = 'on';
        app.UITable.Enable = 'off';
        app.slider_lgain.Enable = 'off';
        app.slider_rgain.Enable = 'off';
        app.PreviousFileButton.Enable ='off';
        app.SelectedFileButton.Enable = 'off';
        app.NextFileButton.Enable = 'off';
    end
    
    function enable_buttons(app)
        app.AudioFileProcessorApp.Interruptible = 'on';
        app.UITable.Enable = 'on';
        app.slider_lgain.Enable = 'on';
        app.slider_rgain.Enable = 'on';
        app.PreviousFileButton.Enable = 'on';
        app.SelectedFileButton.Enable = 'on';
        app.NextFileButton.Enable = 'on';
    end
    
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AudioFileProcessor_OpeningFcn(app)
% Choose default command line output for AudioFileProcessor
global p 

% Add common functions path to the current directory
currentFolder         = pwd; 
CCIMobileFolder       = fileparts(currentFolder);
CommonFunctionsFolder = [CCIMobileFolder '\CommonFunctions\'];
addpath(CommonFunctionsFolder);

% Display the header 
app.Header.ImageSource = [CommonFunctionsFolder '\CCi-MOBILEHeader.png'];
app.Header.ScaleMethod = 'fit';

% Initialize the MAP and signal processing parameters
p = initialize_ACE;

% Turn on the gain adjustment slider (left)
if (isfield(p,'Left')== 1)
    p.General.LeftOn = 1; 
    app.slider_lgain.Value = p.Left.Gain;
    app.text25dB_lgain.Text = ['Gain = ' num2str( p.Left.Gain) 'dB'];
else
    p.General.LeftOn = 0; 
    app.slider_lgain.Enable   ='off';
    app.text25dB_lgain.Enable = 'off';
end
% Turn on the gain adjustment slider (right)
if (isfield(p,'Right')==1)
    p.General.RightOn = 1; 
    app.slider_rgain.Value = p.Right.Gain;
    app.text25dB_rgain.Text = ['Gain = ' num2str( p.Right.Gain) 'dB'];
else
    p.General.RightOn = 0; 
    app.slider_rgain.Enable   ='off';
    app.text25dB_rgain.Enable = 'off';
end

% Update the message box with Subject MAP information
text_msg = ['Subject Name: ' p.General.SubjectName '    Subject ID: ' p.General.SubjectID '     MAP: ' p.General.MapTitle];
app.LoadMAPfileText.Text = text_msg;

        end

        % Callback function
        function DirectoryList_Callback(app, event)
            
        end

        % Button pushed function: NextFileButton
        function NextFileButton_Callback(app, event)
global p
% Close all other figures (e.g., electrodograms) but not this GUI
close all; 
% Make the figure visible again
app.AudioFileProcessorApp.Visible = 'on';

% Turn off the buttons
disable_buttons(app);

if (app.tableIndex(1) + 1 <= length(app.UITable.Data))
    app.tableIndex = app.tableIndex(1) + 1;
    app.Label.Text = cell2mat(app.UITable.Data(app.tableIndex));
end

filename = cell2mat(app.UITable.Data(app.tableIndex(1)));
faddress = strcat(app.folderPath,'\',filename);

stimulate(faddress,p,app);

% Enable all of the buttons
enable_buttons(app);
        end

        % Button pushed function: SelectedFileButton
        function SelectedFileButton_Callback(app, event)
global  p
% Close all other figures (e.g., electrodograms) but not this GUI
close all;
% Make the figure visible again
app.AudioFileProcessorApp.Visible = 'on';

% Turn off the buttons
disable_buttons(app);

filename = cell2mat(app.UITable.Data(app.tableIndex(1)));
faddress = strcat(app.folderPath,'\',filename);

stimulate(faddress,p,app);

% Enable all of the buttons
enable_buttons(app);
        end

        % Button pushed function: PreviousFileButton
        function PreviousFileButton_Callback(app, event)
global  p
% Close all other figures (e.g., electrodograms) but not this GUI
close all; 
% Make the figure visible again
app.AudioFileProcessorApp.Visible = 'on';

% Turn off the buttons
disable_buttons(app);

if (app.tableIndex(1) - 1 > 0)
    app.tableIndex = app.tableIndex(1) - 1;
    app.Label.Text = cell2mat(app.UITable.Data(app.tableIndex));
end

filename = cell2mat(app.UITable.Data(app.tableIndex(1)));
faddress = strcat(app.folderPath,'\',filename);

stimulate(faddress,p,app);

% Enable all of the buttons
enable_buttons(app);
        end

        % Value changed function: slider_lgain
        function slider_lgain_Callback(app, event)
global p
new_value               = app.slider_lgain.Value;
p.Left.Gain             = new_value;
p.Left.gains_dB         = p.Left.Gain + p.Left.BandGains;
p.Left.gains            = 10 .^ (p.Left.gains_dB / 20.0);  
app.text25dB_lgain.Text = ['Gain = ' num2str(p.Left.Gain) 'dB'];
        end

        % Value changed function: slider_rgain
        function slider_rgain_Callback(app, event)
global p
new_value               = app.slider_rgain.Value;
p.Right.Gain            = new_value;
p.Right.gains_dB        = p.Right.Gain + p.Right.BandGains;
p.Right.gains           = 10 .^ (p.Right.gains_dB / 20.0);  
app.text25dB_rgain.Text = ['Gain = ' num2str(p.Right.Gain) 'dB'];
        end

        % Callback function
        function DirectoryListValueChanged(app, event)
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.tableIndex = event.Indices;
            app.Label.Text = cell2mat(app.UITable.Data(app.tableIndex(1)));
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.folderPath = uigetdir; % Calls 'uigetdir' to obtain the directory location from the user
            
            app.AudioFileProcessorApp.Visible = 'off'; % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.AudioFileProcessorApp.Visible = 'on';
            
            cd (app.folderPath); % change directory to current folder
            a = dir('*.wav'); % only select .wav files
            b = {a(:).name}'; % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            
            app.UITable.Data=b; % Displays the directory information to the UITable.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioFileProcessorApp = uifigure('Visible', 'off');
            app.AudioFileProcessorApp.Name = 'AudioFileProcessor';
            app.LocationPanel = uipanel(app.AudioFileProcessorApp);
            app.LocationPanel.Title = 'Location';
            app.LocationText = uilabel(app.LocationPanel);
            app.LocationText.Text = 'Double Click to Open';
            app.DirectoryPanel = uipanel(app.AudioFileProcessorApp);
            app.DirectoryPanel.Title = 'Directory';
            app.UITable = uitable(app.DirectoryPanel);
            app.UITable.ColumnName = {'Folder Content'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectFolderButton = uibutton(app.DirectoryPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.CurrentfileLabel = uilabel(app.DirectoryPanel);
            app.CurrentfileLabel.Text = 'Current file:';
            app.Label = uilabel(app.DirectoryPanel);
            app.Label.Text = '...';
            app.StreamFromListBoxPanel = uipanel(app.AudioFileProcessorApp);
            app.StreamFromListBoxPanel.Title = 'Stream File from File List Box';
            app.PreviousFileButton = uibutton(app.StreamFromListBoxPanel, 'push');
            app.PreviousFileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFileButton_Callback, true);
            app.PreviousFileButton.Text = 'Previous File';
            app.SelectedFileButton = uibutton(app.StreamFromListBoxPanel, 'push');
            app.SelectedFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectedFileButton_Callback, true);
            app.SelectedFileButton.Text = 'Selected File';
            app.NextFileButton = uibutton(app.StreamFromListBoxPanel, 'push');
            app.NextFileButton.ButtonPushedFcn = createCallbackFcn(app, @NextFileButton_Callback, true);
            app.NextFileButton.Text = 'Next File';
            app.LeftGainPanel = uipanel(app.AudioFileProcessorApp);
            app.LeftGainPanel.Title = 'Left Gain (dB)';
            app.slider_lgain = uislider(app.LeftGainPanel);
            app.slider_lgain.Limits = [0 50];
            app.slider_lgain.MajorTicks = [];
            app.slider_lgain.ValueChangedFcn = createCallbackFcn(app, @slider_lgain_Callback, true);
            app.slider_lgain.MinorTicks = [];
            app.slider_lgain.Value = 25;
            app.text25dB_lgain = uilabel(app.LeftGainPanel);
            app.text25dB_lgain.Text = '25dB';
            app.text0dB_lgain = uilabel(app.LeftGainPanel);
            app.text0dB_lgain.Text = '0dB';
            app.text50dB_lgain = uilabel(app.LeftGainPanel);
            app.text50dB_lgain.Text = '50dB';
            app.RightGainPanel = uipanel(app.AudioFileProcessorApp);
            app.RightGainPanel.Title = 'Right Gain (dB)';
            app.slider_rgain = uislider(app.RightGainPanel);
            app.slider_rgain.Limits = [0 50];
            app.slider_rgain.MajorTicks = [];
            app.slider_rgain.ValueChangedFcn = createCallbackFcn(app, @slider_rgain_Callback, true);
            app.slider_rgain.MinorTicks = [];
            app.slider_rgain.Value = 25;
            app.text25dB_rgain = uilabel(app.RightGainPanel);
            app.text25dB_rgain.Text = '25dB';
            app.text0dB_rgain = uilabel(app.RightGainPanel);
            app.text0dB_rgain.Text = '0dB';
            app.text50dB_rgain = uilabel(app.RightGainPanel);
            app.text50dB_rgain.Text = '50dB';
            app.MAPfileLoadPanel = uipanel(app.AudioFileProcessorApp);
            app.LoadMAPfileText = uilabel(app.MAPfileLoadPanel);
            app.LoadMAPfileText.Text = 'Please Load a MAP file';
            app.CCiMOBILECommunicationPanel = uipanel(app.AudioFileProcessorApp);
            app.CCiMOBILECommunicationPanel.Title = 'CCi-MOBILE Communication';
            app.StreamingtoCCiMOBILELampLabel = uilabel(app.CCiMOBILECommunicationPanel);
            app.StreamingtoCCiMOBILELampLabel.Text = 'Streaming to CCi-MOBILE';
            app.StreamingtoCCiMOBILELamp = uilamp(app.CCiMOBILECommunicationPanel);
            app.StreamingtoCCiMOBILELamp.Enable = 'off';
            app.Header = uiimage(app.AudioFileProcessorApp);
            % Show the figure after all components are created
            app.AudioFileProcessorApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioFileProcesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioFileProcessorApp)

            % Execute the startup function
            runStartupFcn(app, @AudioFileProcessor_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioFileProcessorApp)
        end
    end
end