classdef AudioRecorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioRecorderApp     matlab.ui.Figure
        RecordStopPlayPanel  matlab.ui.container.Panel
        buttonRecord         matlab.ui.control.Button
        buttonStop           matlab.ui.control.Button
        buttonPlay           matlab.ui.control.Button
        textStatus           matlab.ui.control.Label
        MicImage             matlab.ui.control.Image
        Header               matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AudioRecorder_OpeningFcn(app)
            % Choose default command line output for AudioRecorder
            global fs; fs = 16000;
            % add common functions path to the current directory
            currentFolder = pwd; 
            CCIMobileFolder = fileparts(currentFolder);
            CommonFunctionsFolder = [CCIMobileFolder '\CommonFunctions\'];
            addpath(CommonFunctionsFolder);
            % Display the header 
            app.Header.ImageSource = [CommonFunctionsFolder '\CCi-MOBILEHeader.png'];
            app.Header.ScaleMethod = 'fit';
            % Display the image
            app.MicImage.ImageSource = [CommonFunctionsFolder '\AudioRecorderMic.png'];
            app.MicImage.ScaleMethod = 'fit';
            
            app.buttonPlay.Enable= 'off';
            app.buttonStop.Enable ='off';
            app.textStatus.Text = 'Press Record to start recording.';
           
        end

        % Button pushed function: buttonPlay
        function buttonPlay_Callback(app, event)
            app.buttonRecord.Enable = 'off';
            app.buttonStop.Enable = 'off';
            app.buttonPlay.Enable = 'off';
            app.textStatus.Text = 'Playing';
            app.textStatus.Text = 'Playing...';
            
            global stop;
            stop = 0;
            if exist('BTEaudio.wav', 'file') == 2
                disp('PLAYING');
                [y, SamplingRate] = audioread('BTEaudio.wav');
                % MATLAB Versions 2016b and newer use 'soundsc' instead of
                % 'sound' function
                soundsc(y,SamplingRate);
                clear y SamplingRate;
            else
                disp('No recording exists.\n');
            end
            
            pause(2);
            app.buttonPlay.Enable ='on';
            app.buttonRecord.Enable='on';
            %set(app.textStatus,'String','  ');
            %app.textStatus.Text = ' ';
        end

        % Button pushed function: buttonRecord
        function buttonRecord_Callback(app, event)
            % hObject    handle to buttonRecord (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if exist('BTEaudio.wav', 'file') == 2
                delete BTEaudio.wav;
            end
            app.buttonPlay.Enable = 'off';
            app.buttonRecord.Enable = 'off';
            app.buttonStop.Enable = 'on';
            drawnow; %set(app.textStatus,'String','Recording');
            %app.textStatus.Text = 'Recording';
            
            global stop; global s; global fs;     
            stop = 0;
            s = initializeBoard;
            dummy_output_buffer = UART_start_buffer;
            audio_stream = []; i = 0;
            while (stop==0)
                if Wait(s)>= 512
                    AD_data_bytes = Read(s, 512);
                    AD_data=typecast(int8(AD_data_bytes), 'int16');
                    left = double(AD_data(1:2:end)); right=double(AD_data(2:2:end));
                    stereo_signal = [left;right];
                    % Following is a slower method and only good for short audio
                    % recordings; consider pre-allocationg audio stream for specific
                    % number of frames for efficiency
                    audio_stream = [audio_stream stereo_signal];
                    if isvalid(s)
                        Write(s, dummy_output_buffer,516);
                    end
                    t = floor(i*0.008); 
                    msg = ['Recording: ' num2str(t) ' s'];
                    drawnow; 
                    clear left; clear right; clear stereo_signal;
                    i = i+1;
                end
            end
            drawnow; 
            %set(app.textStatus,'String','Recording Stopped');
            %app.textStatus.Text = 'Recording Stopped';
            
            if isempty(audio_stream)==0
                audiowrite('BTEaudio.wav',(audio_stream.*7e-5)',fs);
            else
                disp('Audio Stream is empty.\n');
            end
            delete(s); clear s;
        end

        % Button pushed function: buttonStop
        function buttonStop_Callback(app, event)
            % hObject    handle to buttonStop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global stop;
            stop = 1;
            app.buttonPlay.Enable = 'on';
            app.buttonStop.Enable = 'off';
            app.buttonRecord.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioRecorderApp = uifigure('Visible', 'off');
            app.AudioRecorderApp.Name = 'AudioRecorder';
            app.RecordStopPlayPanel = uipanel(app.AudioRecorderApp);
            app.buttonRecord = uibutton(app.RecordStopPlayPanel, 'push');
            app.buttonRecord.ButtonPushedFcn = createCallbackFcn(app, @buttonRecord_Callback, true);
            app.buttonRecord.Text = 'Record';
            app.buttonStop = uibutton(app.RecordStopPlayPanel, 'push');
            app.buttonStop.ButtonPushedFcn = createCallbackFcn(app, @buttonStop_Callback, true);
            app.buttonStop.Text = 'Stop';
            app.buttonPlay = uibutton(app.RecordStopPlayPanel, 'push');
            app.buttonPlay.ButtonPushedFcn = createCallbackFcn(app, @buttonPlay_Callback, true);
            app.buttonPlay.Text = 'Play';
            app.textStatus = uilabel(app.AudioRecorderApp);
            app.textStatus.Text = 'Status';
            app.MicImage = uiimage(app.AudioRecorderApp);
            app.Header = uiimage(app.AudioRecorderApp);
            % Show the figure after all components are created
            app.AudioRecorderApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioRecorder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioRecorderApp)

            % Execute the startup function
            runStartupFcn(app, @AudioRecorder_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioRecorderApp)
        end
    end
end