classdef AudioScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioScopeApp    matlab.ui.Figure
        AudioScopeGraph  matlab.ui.control.UIAxes
        StartStopPanel   matlab.ui.container.Panel
        buttonStart      matlab.ui.control.Button
        buttonStop       matlab.ui.control.Button
        Header           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AudioScope_OpeningFcn(app)

            % Choose default command line output for AudioScope
            global fs; fs = 16000;
            % add common functions path to the current directory
            currentFolder = pwd; CCIMobileFolder = fileparts(currentFolder); %currentFolder(1:end-10);
            CommonFunctionsFolder = [CCIMobileFolder '\CommonFunctions\'];
            addpath(CommonFunctionsFolder);
            % Display the header 
            app.Header.ImageSource = [CommonFunctionsFolder '\CCi-MOBILEHeader.png'];
            app.Header.ScaleMethod = 'fit';
        end

        % Button pushed function: buttonStart
        function buttonStart_Callback(app, event)

            global stop; global s; global fs;
            stop = 0;
            app.buttonStart.Enable = 'off';
            s = initializeBoard;
            
            % Initially set the plot figure
            maxA        = 4000; 
            minA        = -4000;
            index       = 1; 
            a           = zeros(1,16000); 
            nSamples    = numel(a); 
            app.AudioScopeGraph.XLabel.String = 'Time (s)';
            app.AudioScopeGraph.YLim   = ([minA maxA]);
            app.AudioScopeGraph.XLim   = ([0 nSamples/fs]);
            plot(app.AudioScopeGraph,(1:nSamples)/fs,a(:,index:index+nSamples-1))
            
            dummy_output_buffer = UART_start_buffer;
            
            while (stop==0)
                if Wait(s)>= 512
                    AD_data_bytes = Read(s, 512);
                    AD_data       = typecast(int8(AD_data_bytes), 'int16');
                    
                    % Users can manually select which ear to display (Default: Left)
                    %left       = AD_data(1:2:end);
                    right       = AD_data(2:2:end);
                    
                    % Select which ear to display (Default: Left)
                    mic_data      = right;
                 
                    %% NEW! Code to verify (Monday)
                    % Plots microphone selected only
                    a(nSamples-127:end) = mic_data; 
                    % Plot the updated 'a'
                    plot(app.AudioScopeGraph,(1:nSamples)/fs,a(:,index:index+nSamples-1));
                    app.AudioScopeGraph.YLim   = ([minA maxA]);
                    % Update the display
                    drawnow 
                    % Update the new data stream
                    a(1:nSamples-128) = a(129:nSamples);
                    
                    % NOTE: No electrical stimuli from the microphone data
                    % is sent to the RF coil (DUMMY BUFFERS ONLY)
                    if isvalid(s)
                        Write(s, dummy_output_buffer,516);
                    end
            
                end
            
            end
        end

        % Button pushed function: buttonStop
        function buttonStop_Callback(app, event)
            global stop; global s;
            stop = 1;
            delete(s); clear s;
            app.buttonStart.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioScopeApp = uifigure('Visible', 'off');
            app.AudioScopeApp.Name = 'AudioScope';
            app.AudioScopeGraph = uiaxes(app.AudioScopeApp);
            app.StartStopPanel = uipanel(app.AudioScopeApp);
            app.buttonStart = uibutton(app.StartStopPanel, 'push');
            app.buttonStart.ButtonPushedFcn = createCallbackFcn(app, @buttonStart_Callback, true);
            app.buttonStart.Text = 'Start';
            app.buttonStop = uibutton(app.StartStopPanel, 'push');
            app.buttonStop.ButtonPushedFcn = createCallbackFcn(app, @buttonStop_Callback, true);
            app.buttonStop.Text = 'Stop';
            app.Header = uiimage(app.AudioScopeApp);
            % Show the figure after all components are created
            app.AudioScopeApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioScope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioScopeApp)

            % Execute the startup function
            runStartupFcn(app, @AudioScope_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioScopeApp)
        end
    end
end