classdef CustomSignalProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CustomSignalProcessingApp  matlab.ui.Figure
        Header                     matlab.ui.control.Image
        LoadMAPtobeginButton       matlab.ui.control.Button
        LeftMAPPanel               matlab.ui.container.Panel
        SRtitleL                   matlab.ui.control.Label
        SRmapL                     matlab.ui.control.Label
        SRstatusL                  matlab.ui.control.Label
        SRadjustedL                matlab.ui.control.Label
        SRmapLval                  matlab.ui.control.TextArea
        SRstatusLval               matlab.ui.control.TextArea
        SRadjustedLval             matlab.ui.control.TextArea
        TPPStitleL                 matlab.ui.control.Label
        TPPSmapL                   matlab.ui.control.Label
        TPPSstatusL                matlab.ui.control.Label
        TPPSadjustedL              matlab.ui.control.Label
        TPPSmapLval                matlab.ui.control.TextArea
        TPPSstatusLval             matlab.ui.control.TextArea
        TPPSadjustedLval           matlab.ui.control.TextArea
        PPFPCtitleL                matlab.ui.control.Label
        PPFPCmapL                  matlab.ui.control.Label
        PPFPCstatusL               matlab.ui.control.Label
        PPFPCadjustedL             matlab.ui.control.Label
        PPFPCmapLval               matlab.ui.control.TextArea
        PPFPCstatusLval            matlab.ui.control.TextArea
        PPFPCadjustedLval          matlab.ui.control.TextArea
        BStitleL                   matlab.ui.control.Label
        BSmapL                     matlab.ui.control.Label
        BSstatusL                  matlab.ui.control.Label
        BSadjustedL                matlab.ui.control.Label
        BSmapLval                  matlab.ui.control.TextArea
        BSstatusLval               matlab.ui.control.TextArea
        BSadjustedLval             matlab.ui.control.TextArea
        PWlabelL                   matlab.ui.control.Label
        PWmapL                     matlab.ui.control.Label
        PWstatusL                  matlab.ui.control.Label
        PWmapLval                  matlab.ui.control.TextArea
        PWstatusLval               matlab.ui.control.TextArea
        PWadjustedL                matlab.ui.control.Label
        PWadjustedLval             matlab.ui.control.TextArea
        StrategyButtonGroup        matlab.ui.container.ButtonGroup
        ACEButton                  matlab.ui.control.RadioButton
        CustomButton               matlab.ui.control.RadioButton
        StreamLampLabel            matlab.ui.control.Label
        StreamLamp                 matlab.ui.control.Lamp
        SelectedFileButton         matlab.ui.control.Button
        NextFileButton             matlab.ui.control.Button
        LeftGainPanel              matlab.ui.container.Panel
        slider_lgain               matlab.ui.control.Slider
        text25dB_lgain             matlab.ui.control.Label
        text0dB_lgain              matlab.ui.control.Label
        text50dB_lgain             matlab.ui.control.Label
        RightMAPPanel              matlab.ui.container.Panel
        SRtitleR                   matlab.ui.control.Label
        SRmapRval                  matlab.ui.control.TextArea
        SRstatusRval               matlab.ui.control.TextArea
        SRadjustedRval             matlab.ui.control.TextArea
        TPPStitleR                 matlab.ui.control.Label
        TPPSmapRval                matlab.ui.control.TextArea
        TPPSstatusRval             matlab.ui.control.TextArea
        TPPSadjustedRval           matlab.ui.control.TextArea
        PPFPCtitleR                matlab.ui.control.Label
        PPFPCmapRval               matlab.ui.control.TextArea
        PPFPCstatusRval            matlab.ui.control.TextArea
        PPFPCadjustedRval          matlab.ui.control.TextArea
        BStitleR                   matlab.ui.control.Label
        BSmapRval                  matlab.ui.control.TextArea
        BSstatusRval               matlab.ui.control.TextArea
        BSadjustedRval             matlab.ui.control.TextArea
        PWtitleR                   matlab.ui.control.Label
        SRmapR                     matlab.ui.control.Label
        SRstatusR                  matlab.ui.control.Label
        SRadjustedR                matlab.ui.control.Label
        TPPSmapR                   matlab.ui.control.Label
        TPPSstatusR                matlab.ui.control.Label
        TPPSadjustedR              matlab.ui.control.Label
        PPFPCmapR                  matlab.ui.control.Label
        PPFPCstatusR               matlab.ui.control.Label
        PPFPCadjustedR             matlab.ui.control.Label
        BSmapR                     matlab.ui.control.Label
        BSstatusR                  matlab.ui.control.Label
        BSadjustedR                matlab.ui.control.Label
        PWmapR                     matlab.ui.control.Label
        PWstatusR                  matlab.ui.control.Label
        PWmapRval                  matlab.ui.control.TextArea
        PWstatusRval               matlab.ui.control.TextArea
        PWadjustedR                matlab.ui.control.Label
        PWadjustedRval             matlab.ui.control.TextArea
        RightGainPanel             matlab.ui.container.Panel
        slider_rgain               matlab.ui.control.Slider
        text25dB_rgain             matlab.ui.control.Label
        text0dB_rgain              matlab.ui.control.Label
        text50dB_rgain             matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        SelectFolderButton         matlab.ui.control.Button
    end

properties (Access = private)
        tableIndex % index of selected item in table
        folderPath % path to current .wav files folder
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Add common functions path to the current directory
currentFolder         = pwd; 
CCIMobileFolder       = fileparts(currentFolder);
CommonFunctionsFolder = [CCIMobileFolder '\CommonFunctions\'];
addpath(CommonFunctionsFolder);

% Display the header 
app.Header.ImageSource = [CommonFunctionsFolder '\CCi-MOBILEHeader.png'];
app.Header.ScaleMethod = 'fit';
        end

        % Button pushed function: LoadMAPtobeginButton
        function LoadMAPtobeginButtonPushed(app, event)
        % Choose default command line output for AudioFileProcessor
        global p 
        % This function is used to initialize ACE after a MAP is selected
        realtime = 0;
        % Priority parameters to be released in Ver 2.2e
        priority = 0;
        manual   = 0;
        [p,app] = initialization(realtime,0,manual,app);
        
        % Turn on the gain adjustment slider (left)
        if (isfield(p,'Left')== 1)
            p.General.LeftOn = 1; 
            app.slider_lgain.Value    = p.Left.Gain;
            app.text25dB_lgain.Text   = ['Gain = ' num2str( p.Left.Gain) 'dB'];
        else
            p.General.LeftOn = 0; 
            app.slider_lgain.Enable   ='off';
            app.text25dB_lgain.Enable = 'off';
        end
        
        % Turn on the gain adjustment slider (right)
        if (isfield(p,'Right')==1)
            p.General.RightOn = 1; 
            app.slider_rgain.Value     = p.Right.Gain;
            app.text25dB_rgain.Text    = ['Gain = ' num2str( p.Right.Gain) 'dB'];
        else
            p.General.RightOn = 0; 
            app.slider_rgain.Enable   ='off';
            app.text25dB_rgain.Enable = 'off';
        end
        
        % Update the button and disable
        subjectMAP = ['Subject MAP: ' p.General.MapTitle];
        app.LoadMAPtobeginButton.Text = subjectMAP;
        app.LoadMAPtobeginButton.Enable = 'off';
        
        end

        % Callback function
        function PreviousFileButtonPushed(app, event)
% Load the MAP parameter (p)
global p
% Close all other figures (e.g., electrodograms) but not this GUI
close all; 

if (app.tableIndex(1) - 1 > 0)
    app.tableIndex = app.tableIndex(1) - 1;
    % Update the label text
    % app.Label.Text = cell2mat(app.UITable.Data(app.tableIndex));
end

filename = cell2mat(app.UITable.Data(app.tableIndex(1)));
faddress = strcat(app.folderPath,'\',filename);

% Run the signal processing
stimulate(faddress,p,app);
        end

        % Button pushed function: SelectedFileButton
        function SelectedFileButtonPushed(app, event)
% Load the MAP parameter (p)
global p
% Close all other figures (e.g., electrodograms) but not this GUI
close all; 

filename = cell2mat(app.UITable.Data(app.tableIndex(1)));
faddress = strcat(app.folderPath,'\',filename);

% Run the signal processing
stimulate(faddress,p,app);
        end

        % Button pushed function: NextFileButton
        function NextFileButtonPushed(app, event)
% Load the MAP parameter (p)
global p
% Close all other figures (e.g., electrodograms) but not this GUI
close all; 

if (app.tableIndex(1) + 1 <= length(app.UITable.Data))
    app.tableIndex = app.tableIndex(1) + 1;
    app.Label.Text = cell2mat(app.UITable.Data(app.tableIndex));
end

filename = cell2mat(app.UITable.Data(app.tableIndex(1)));
faddress = strcat(app.folderPath,'\',filename);

% Run the signal processing
stimulate(faddress,p,app);
        end

        % Value changed function: slider_lgain
        function slider_lgainValueChanged(app, event)
global p
new_value               = app.slider_lgain.Value;
p.Left.Gain             = new_value;
p.Left.gains_dB         = p.Left.Gain + p.Left.BandGains;
p.Left.gains            = 10 .^ (p.Left.gains_dB / 20.0);  
app.text25dB_lgain.Text = ['Gain = ' num2str(p.Left.Gain) 'dB'];
        end

        % Value changed function: slider_rgain
        function slider_rgainValueChanged(app, event)
global p
new_value               = app.slider_rgain.Value;
p.Right.Gain            = new_value;
p.Right.gains_dB        = p.Right.Gain + p.Right.BandGains;
p.Right.gains           = 10 .^ (p.Right.gains_dB / 20.0);  
app.text25dB_rgain.Text = ['Gain = ' num2str(p.Right.Gain) 'dB'];
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
app.tableIndex = event.Indices;
% Update the label next to the current folder (missing in CSP)
% app.Label.Text = cell2mat(app.UITable.Data(app.tableIndex(1)));
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
% Calls 'uigetdir' to obtain the directory location from the user
        app.folderPath = uigetdir; 
% Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
        app.CustomSignalProcessingApp.Visible = 'off'; 
        app.CustomSignalProcessingApp.Visible = 'on';
% Change directory to current folder           
        cd (app.folderPath); 
% Only select .wav files
        a = dir('*.wav'); 
% Gets the name of the files/folders of the contents and stores them appropriately in a cell array
        b = {a(:).name}';
% Displays the directory information to the UITable.
        app.UITable.Data=b; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CustomSignalProcessingApp = uifigure('Visible', 'off');
            app.CustomSignalProcessingApp.Name = 'UI Figure';
            app.Header = uiimage(app.CustomSignalProcessingApp);
            app.LoadMAPtobeginButton = uibutton(app.CustomSignalProcessingApp, 'push');
            app.LoadMAPtobeginButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMAPtobeginButtonPushed, true);
            app.LoadMAPtobeginButton.Text = 'Load MAP to begin...';
            app.LeftMAPPanel = uipanel(app.CustomSignalProcessingApp);
            app.LeftMAPPanel.Title = 'Left MAP';
            app.SRtitleL = uilabel(app.LeftMAPPanel);
            app.SRtitleL.Text = 'Stimulation Rate (pps/ch: pulses per second per channel)';
            app.SRmapL = uilabel(app.LeftMAPPanel);
            app.SRmapL.Text = 'MAP-Defined';
            app.SRstatusL = uilabel(app.LeftMAPPanel);
            app.SRstatusL.Text = 'STATUS';
            app.SRadjustedL = uilabel(app.LeftMAPPanel);
            app.SRadjustedL.Text = 'Adjusted';
            app.SRmapLval = uitextarea(app.LeftMAPPanel);
            app.SRstatusLval = uitextarea(app.LeftMAPPanel);
            app.SRadjustedLval = uitextarea(app.LeftMAPPanel);
            app.TPPStitleL = uilabel(app.LeftMAPPanel);
            app.TPPStitleL.Text = 'Total Pulses Per Stimulation Cycle';
            app.TPPSmapL = uilabel(app.LeftMAPPanel);
            app.TPPSmapL.Text = 'MAP-Defined';
            app.TPPSstatusL = uilabel(app.LeftMAPPanel);
            app.TPPSstatusL.Text = 'STATUS';
            app.TPPSadjustedL = uilabel(app.LeftMAPPanel);
            app.TPPSadjustedL.Text = 'Adjusted';
            app.TPPSmapLval = uitextarea(app.LeftMAPPanel);
            app.TPPSstatusLval = uitextarea(app.LeftMAPPanel);
            app.TPPSadjustedLval = uitextarea(app.LeftMAPPanel);
            app.PPFPCtitleL = uilabel(app.LeftMAPPanel);
            app.PPFPCtitleL.Text = 'Pulses Per Frame Per Channel';
            app.PPFPCmapL = uilabel(app.LeftMAPPanel);
            app.PPFPCmapL.Text = 'MAP-Defined';
            app.PPFPCstatusL = uilabel(app.LeftMAPPanel);
            app.PPFPCstatusL.Text = 'STATUS';
            app.PPFPCadjustedL = uilabel(app.LeftMAPPanel);
            app.PPFPCadjustedL.Text = 'Adjusted';
            app.PPFPCmapLval = uitextarea(app.LeftMAPPanel);
            app.PPFPCstatusLval = uitextarea(app.LeftMAPPanel);
            app.PPFPCadjustedLval = uitextarea(app.LeftMAPPanel);
            app.BStitleL = uilabel(app.LeftMAPPanel);
            app.BStitleL.Text = 'Block Shift';
            app.BSmapL = uilabel(app.LeftMAPPanel);
            app.BSmapL.Text = 'MAP-Defined';
            app.BSstatusL = uilabel(app.LeftMAPPanel);
            app.BSstatusL.Text = 'STATUS';
            app.BSadjustedL = uilabel(app.LeftMAPPanel);
            app.BSadjustedL.Text = 'Adjusted';
            app.BSmapLval = uitextarea(app.LeftMAPPanel);
            app.BSstatusLval = uitextarea(app.LeftMAPPanel);
            app.BSadjustedLval = uitextarea(app.LeftMAPPanel);
            app.PWlabelL = uilabel(app.LeftMAPPanel);
            app.PWlabelL.Text = 'Pulse Width';
            app.PWmapL = uilabel(app.LeftMAPPanel);
            app.PWmapL.Text = 'MAP-Defined';
            app.PWstatusL = uilabel(app.LeftMAPPanel);
            app.PWstatusL.Text = 'STATUS';
            app.PWmapLval = uitextarea(app.LeftMAPPanel);
            app.PWstatusLval = uitextarea(app.LeftMAPPanel);
            app.PWadjustedL = uilabel(app.LeftMAPPanel);
            app.PWadjustedL.Text = 'Adjusted';
            app.PWadjustedLval = uitextarea(app.LeftMAPPanel);
            app.StrategyButtonGroup = uibuttongroup(app.CustomSignalProcessingApp);
            app.StrategyButtonGroup.Title = 'Strategy';
            app.ACEButton = uiradiobutton(app.StrategyButtonGroup);
            app.ACEButton.Text = 'ACE';
            app.ACEButton.Value = true;
            app.CustomButton = uiradiobutton(app.StrategyButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.StreamLampLabel = uilabel(app.CustomSignalProcessingApp);
            app.StreamLampLabel.Text = 'Stream';
            app.StreamLamp = uilamp(app.CustomSignalProcessingApp);
            app.StreamLamp.Enable = 'off';
            app.SelectedFileButton = uibutton(app.CustomSignalProcessingApp, 'push');
            app.SelectedFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectedFileButtonPushed, true);
            app.SelectedFileButton.Text = 'Selected File';
            app.NextFileButton = uibutton(app.CustomSignalProcessingApp, 'push');
            app.NextFileButton.ButtonPushedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.NextFileButton.Text = 'Next File';
            app.LeftGainPanel = uipanel(app.CustomSignalProcessingApp);
            app.LeftGainPanel.Title = 'Left Gain (dB)';
            app.slider_lgain = uislider(app.LeftGainPanel);
            app.slider_lgain.Limits = [0 50];
            app.slider_lgain.MajorTicks = [];
            app.slider_lgain.ValueChangedFcn = createCallbackFcn(app, @slider_lgainValueChanged, true);
            app.slider_lgain.MinorTicks = [];
            app.slider_lgain.Value = 25;
            app.text25dB_lgain = uilabel(app.LeftGainPanel);
            app.text25dB_lgain.Text = '25dB';
            app.text0dB_lgain = uilabel(app.LeftGainPanel);
            app.text0dB_lgain.Text = '0dB';
            app.text50dB_lgain = uilabel(app.LeftGainPanel);
            app.text50dB_lgain.Text = '50dB';
            app.RightMAPPanel = uipanel(app.CustomSignalProcessingApp);
            app.RightMAPPanel.Title = 'Right MAP';
            app.SRtitleR = uilabel(app.RightMAPPanel);
            app.SRtitleR.Text = 'Stimulation Rate (pps/ch: pulses per second per channel)';
            app.SRmapRval = uitextarea(app.RightMAPPanel);
            app.SRstatusRval = uitextarea(app.RightMAPPanel);
            app.SRadjustedRval = uitextarea(app.RightMAPPanel);
            app.TPPStitleR = uilabel(app.RightMAPPanel);
            app.TPPStitleR.Text = 'Total Pulses Per Stimulation Cycle';
            app.TPPSmapRval = uitextarea(app.RightMAPPanel);
            app.TPPSstatusRval = uitextarea(app.RightMAPPanel);
            app.TPPSadjustedRval = uitextarea(app.RightMAPPanel);
            app.PPFPCtitleR = uilabel(app.RightMAPPanel);
            app.PPFPCtitleR.Text = 'Pulses Per Frame Per Channel';
            app.PPFPCmapRval = uitextarea(app.RightMAPPanel);
            app.PPFPCstatusRval = uitextarea(app.RightMAPPanel);
            app.PPFPCadjustedRval = uitextarea(app.RightMAPPanel);
            app.BStitleR = uilabel(app.RightMAPPanel);
            app.BStitleR.Text = 'Block Shift';
            app.BSmapRval = uitextarea(app.RightMAPPanel);
            app.BSstatusRval = uitextarea(app.RightMAPPanel);
            app.BSadjustedRval = uitextarea(app.RightMAPPanel);
            app.PWtitleR = uilabel(app.RightMAPPanel);
            app.PWtitleR.Text = 'Pulse Width';
            app.SRmapR = uilabel(app.RightMAPPanel);
            app.SRmapR.Text = 'MAP-Defined';
            app.SRstatusR = uilabel(app.RightMAPPanel);
            app.SRstatusR.Text = 'STATUS';
            app.SRadjustedR = uilabel(app.RightMAPPanel);
            app.SRadjustedR.Text = 'Adjusted';
            app.TPPSmapR = uilabel(app.RightMAPPanel);
            app.TPPSmapR.Text = 'MAP-Defined';
            app.TPPSstatusR = uilabel(app.RightMAPPanel);
            app.TPPSstatusR.Text = 'STATUS';
            app.TPPSadjustedR = uilabel(app.RightMAPPanel);
            app.TPPSadjustedR.Text = 'Adjusted';
            app.PPFPCmapR = uilabel(app.RightMAPPanel);
            app.PPFPCmapR.Text = 'MAP-Defined';
            app.PPFPCstatusR = uilabel(app.RightMAPPanel);
            app.PPFPCstatusR.Text = 'STATUS';
            app.PPFPCadjustedR = uilabel(app.RightMAPPanel);
            app.PPFPCadjustedR.Text = 'Adjusted';
            app.BSmapR = uilabel(app.RightMAPPanel);
            app.BSmapR.Text = 'MAP-Defined';
            app.BSstatusR = uilabel(app.RightMAPPanel);
            app.BSstatusR.Text = 'STATUS';
            app.BSadjustedR = uilabel(app.RightMAPPanel);
            app.BSadjustedR.Text = 'Adjusted';
            app.PWmapR = uilabel(app.RightMAPPanel);
            app.PWmapR.Text = 'MAP-Defined';
            app.PWstatusR = uilabel(app.RightMAPPanel);
            app.PWstatusR.Text = 'STATUS';
            app.PWmapRval = uitextarea(app.RightMAPPanel);
            app.PWstatusRval = uitextarea(app.RightMAPPanel);
            app.PWadjustedR = uilabel(app.RightMAPPanel);
            app.PWadjustedR.Text = 'Adjusted';
            app.PWadjustedRval = uitextarea(app.RightMAPPanel);
            app.RightGainPanel = uipanel(app.CustomSignalProcessingApp);
            app.RightGainPanel.Title = 'Right Gain (dB)';
            app.slider_rgain = uislider(app.RightGainPanel);
            app.slider_rgain.Limits = [0 50];
            app.slider_rgain.MajorTicks = [];
            app.slider_rgain.ValueChangedFcn = createCallbackFcn(app, @slider_rgainValueChanged, true);
            app.slider_rgain.MinorTicks = [];
            app.slider_rgain.Value = 25;
            app.text25dB_rgain = uilabel(app.RightGainPanel);
            app.text25dB_rgain.Text = '25dB';
            app.text0dB_rgain = uilabel(app.RightGainPanel);
            app.text0dB_rgain.Text = '0dB';
            app.text50dB_rgain = uilabel(app.RightGainPanel);
            app.text50dB_rgain.Text = '50dB';
            app.UITable = uitable(app.CustomSignalProcessingApp);
            app.UITable.ColumnName = {'Directory Content'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = false;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectFolderButton = uibutton(app.CustomSignalProcessingApp, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            % Show the figure after all components are created
            app.CustomSignalProcessingApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomSignalProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CustomSignalProcessingApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CustomSignalProcessingApp)
        end
    end
end