classdef RealtimeStimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RealTimeStimulatorApp     matlab.ui.Figure
        Header                    matlab.ui.control.Image
        LeftPanel                 matlab.ui.container.Panel
        ActiveSwitch_L            matlab.ui.control.Switch
        slider_sensitivity_left   matlab.ui.control.Slider
        slider_gain_left          matlab.ui.control.Slider
        slider_volume_left        matlab.ui.control.Slider
        text25dB_lgain            matlab.ui.control.Label
        text0dB_lgain             matlab.ui.control.Label
        text50dB_lgain            matlab.ui.control.Label
        text25dB_lsens            matlab.ui.control.Label
        text0dB_lsens             matlab.ui.control.Label
        text50dB_lsens            matlab.ui.control.Label
        text25dB_lvol             matlab.ui.control.Label
        text0dB_lvol              matlab.ui.control.Label
        text50dB_lvol             matlab.ui.control.Label
        LsensitivityText          matlab.ui.control.Label
        LgainText                 matlab.ui.control.Label
        LvolumeText               matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        ActiveSwitch_R            matlab.ui.control.Switch
        slider_sensitivity_right  matlab.ui.control.Slider
        slider_gain_right         matlab.ui.control.Slider
        slider_volume_right       matlab.ui.control.Slider
        text25dB_rgain            matlab.ui.control.Label
        text0dB_rgain             matlab.ui.control.Label
        text50dB_rgain            matlab.ui.control.Label
        text25dB_rsens            matlab.ui.control.Label
        text0dB_rsens             matlab.ui.control.Label
        text50dB_rsens            matlab.ui.control.Label
        text25dB_rvol             matlab.ui.control.Label
        text0dB_rvol              matlab.ui.control.Label
        text50dB_rvol             matlab.ui.control.Label
        RsensitivityText          matlab.ui.control.Label
        RgainText                 matlab.ui.control.Label
        RvolumeText               matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        CCiMOBILEActivityPanel    matlab.ui.container.Panel
        Label                     matlab.ui.control.Label
        StreamingLamp             matlab.ui.control.Lamp
        LampDescription           matlab.ui.control.Label
        LampStatusText            matlab.ui.control.Label
    end

    
    properties (Access = private)
        p   % map structure
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Add common functions path to the current directory
currentFolder         = pwd; 
CCIMobileFolder       = fileparts(currentFolder);
CommonFunctionsFolder = [CCIMobileFolder '\CommonFunctions\'];
addpath(CommonFunctionsFolder);

% Display the header 
app.Header.ImageSource = [CommonFunctionsFolder '\CCi-MOBILEHeader.png'];
app.Header.ScaleMethod = 'fit';

% Initialize ACE
app.p = initialize_ACE_integer_ppf;

% Set the default inactivity
app.p.stop = 0;
app.LampStatusText.Text = ['CCi-MOBILE: Inactive'];
% Change the lamp status to RED
app.StreamingLamp.Color = [0.81,0.02,0.02]; 

% Adjust the sensitivity values (LEFT)
if (isfield(app.p,'Left')==1)
    app.p.General.LeftOn              = 1; 
    app.slider_sensitivity_left.Value = app.p.Left.Sensitivity;
    app.slider_gain_left.Value        = app.p.Left.Gain;
    app.slider_volume_left.Value      = app.p.Left.Volume;
    % Update the label values
    app.LsensitivityText.Text  = ['Sensitivity = ' num2str(app.p.Left.Sensitivity)];
    app.LgainText.Text         = ['Gain = ' num2str( app.p.Left.Gain) ' dB'];
    app.LvolumeText.Text       = ['Volume = ' num2str(app.p.Left.Volume)];
    app.ActiveSwitch_L.Value   = 'Active';
else
    app.p.General.LeftOn               = 0; 
    app.slider_sensitivity_left.Enable = 'off';
    app.slider_gain_left.Enable        = 'off';
    app.slider_volume_left.Enable      = 'off';
    app.LsensitivityText.Enable        = 'off';
    app.LgainText.Enable               = 'off';
    app.LvolumeText.Enable             = 'off';
    app.ActiveSwitch_L.Value           = 'Inactive';
    app.ActiveSwitch_L.Enable          = 'off';
end

% Adjust the sensitivity values (RIGHT)
if (isfield(app.p,'Right')==1)
    app.p.General.RightOn              = 1; 
    app.slider_sensitivity_right.Value = app.p.Right.Sensitivity;
    app.slider_gain_right.Value        = app.p.Right.Gain;
    app.slider_volume_right.Value      = app.p.Right.Volume;
    % Update the label values
    app.RsensitivityText.Text  = ['Sensitivity = ' num2str(app.p.Right.Sensitivity)];
    app.RgainText.Text         = ['Gain = ' num2str( app.p.Right.Gain) ' dB'];
    app.RvolumeText.Text       = ['Volume = ' num2str(app.p.Right.Volume)];
    app.ActiveSwitch_R.Value   = 'Active';
else
    app.p.General.RightOn               = 0; 
    app.slider_sensitivity_right.Enable = 'off';
    app.slider_gain_right.Enable        = 'off';
    app.slider_volume_right.Enable      = 'off';
    app.RsensitivityText.Enable         = 'off';
    app.RgainText.Enable                = 'off';
    app.RvolumeText.Enable              = 'off';
    app.ActiveSwitch_R.Value            = 'Inactive';
    app.ActiveSwitch_R.Enable           = 'off';
end
        end

        % Value changed function: slider_sensitivity_left
        function slider_sensitivity_leftValueChanged(app, event)
% Update the sensitivity value (LEFT)
new_value                 = app.slider_sensitivity_left.Value;
app.p.Left.Sensitivity    = new_value;
app.p.Left.scale_factor   = app.p.Left.Sensitivity/32768;
% Update the text value (L)
app.LsensitivityText.Text =  ['Sensitivity = ' num2str(app.p.Left.Sensitivity) ];
        end

        % Value changed function: slider_gain_left
        function slider_gain_leftValueChanged(app, event)
% Update the gain value (LEFT)
new_value               = app.slider_gain_left.Value;
app.p.Left.Gain         = new_value;
app.p.Left.gains_dB     = app.p.Left.Gain + app.p.Left.BandGains;
app.p.Left.gains        = 10 .^ (app.p.Left.gains_dB / 20.0); 
% Update the text value (L)
app.LgainText.Text = ['Gain = ' num2str(app.p.Left.Gain) ' dB'];
        end

        % Value changed function: slider_volume_left
        function slider_volume_leftValueChanged(app, event)
% Update the volume value (LEFT)
new_value               = app.slider_volume_left.Value;
app.p.Left.Volume       = new_value;
app.p.Left.volume_level = app.p.Left.Volume / 10;
% Update the text value (L)
app.LvolumeText.Text     = ['Volume = ' num2str(app.p.Left.Volume)];
        end

        % Value changed function: slider_sensitivity_right
        function slider_sensitivity_rightValueChanged(app, event)
% Update the sensitivity value (RIGHT)
new_value                  = app.slider_sensitivity_right.Value;
app.p.Right.Sensitivity    = new_value;
app.p.Right.scale_factor   = app.p.Right.Sensitivity/32768;
% Update the text value (R)
app.RsensitivityText.Text =  ['Sensitivity = ' num2str(app.p.Right.Sensitivity) ];
        end

        % Value changed function: slider_gain_right
        function slider_gain_rightValueChanged(app, event)
% Update the gain value (RIGHT)
new_value               = app.slider_gain_right.Value;
app.p.Right.Gain        = new_value;
app.p.Right.gains_dB    = app.p.Right.Gain + app.p.Right.BandGains;
app.p.Right.gains       = 10 .^ (app.p.Right.gains_dB / 20.0); 
% Update the text value (R)
app.RgainText.Text      = ['Gain = ' num2str(app.p.Right.Gain) ' dB'];
        end

        % Value changed function: slider_volume_right
        function slider_volume_rightValueChanged(app, event)
% Update the volume value (RIGHT)
new_value                = app.slider_volume_right.Value;
app.p.Right.Volume       = new_value;
app.p.Right.volume_level = app.p.Right.Volume / 10;
% Update the text value (R)
app.RvolumeText.Text     = ['Volume = ' num2str(app.p.Right.Volume)];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
% Turn off the start button
app.StartButton.Enable    = 'off';
app.p.stop                = 0;
app.StopButton.Enable     = 'on';

% Initialize the board with buffer stimulus
s            = initializeBoard(app.p);
outputBuffer = create_output_buffer(app.p);

% Generate stimuli for the left 
if (isfield(app.p,'Left') ==1)
    sine_token_l=uint8(175.*sin(2.*pi.*(0:1:app.p.Left.pulses_per_frame).*0.01)); %figure; plot(sine_token);
    indL = 1;
    bufferHistory_left = (zeros(1, app.p.Left.block_size - app.p.Left.block_shift));
end
% Generate stimuli for the right
if (isfield(app.p,'Right') ==1)
    sine_token_r=uint8(175.*sin(2.*pi.*(0:1:app.p.Right.pulses_per_frame).*0.01)); %figure; plot(sine_token);
    indR = 1;
    bufferHistory_right = (zeros(1, app.p.Right.block_size - app.p.Right.block_shift));
end

% Clear previous stimulation buffers by sending null frames
frame_no=1;
while frame_no<3 % send at least 2 null frames to clear out memory
    if Wait(s)>= 512
        AD_data_bytes = Read(s, 512);
        outputBuffer(133:248) = uint8(0); %zero out left amplitudes, if not active
        outputBuffer(391:506) = uint8(0); %zero out right amplitudes
        Write(s, outputBuffer,516); % Write output to the board
        frame_no = frame_no+1;
    end
end

while app.p.stop == 0 

    if Wait(s)>= 512
        % Update the streaming light to green
        app.StreamingLamp.Interruptible = 'on';
        app.StreamingLamp.Visible = 'on' ;
        app.StreamingLamp.Color   = [0.39,0.83,0.07];         % green
        app.LampStatusText.Text   = ['CCi-MOBILE: Active (Real-time)'];
        pause(0.0001)
        
        % Read audio from BTE
        AD_data_bytes = Read(s, 512); 
        % Type cast to short 16 bits
        AD_data=typecast(int8(AD_data_bytes), 'int16');     
        
        if (app.p.General.LeftOn == 1)
            audio_left   = double(AD_data(1:2:end)); 
            % Type cast to double for processing
            audio_left   =  (app.p.Left.scale_factor).*audio_left; 
            % 2.3/32768 at 25 dB gain or 1/32768 at 33dB gain for 1kHz at 65dB SPl to equate to MCL p.sensitivity
            stimuli.left = ACE_Processing_Realtime(audio_left, bufferHistory_left, app.p.Left);
            
            a=7;
            %for (i=1:p.Left.pulses_per_frame)
            for (i=1:numel(stimuli.left.electrodes))
                % Left Electrodes
                outputBuffer(a) = uint8(stimuli.left.electrodes(i)); a=a+1; 
            end
            
            a= 133;
            %for (i=1:p.Left.pulses_per_frame)
            for (i=1:numel(stimuli.left.electrodes))
                % Left Amplitudes
                outputBuffer(a) = uint8(stimuli.left.current_levels(i)); a=a+1; 
            end
            bufferHistory_left = audio_left(app.p.Left.block_size-app.p.Left.NHIST+1:end);
        else
            %a= 133;
            % Zero out left amplitudes, if not active
            outputBuffer(133:248) = uint8(0); 
        end
        
        if (app.p.General.RightOn == 1)
            audio_right   = double(AD_data(2:2:end));
            % Type cast to double for processing
            audio_right   = (app.p.Right.scale_factor).*audio_right;
             % 2.3/32768 at 25 dB gain or 1/32768 at 33dB gain for 1kHz at 65dB SPl to equate to MCL p.sensitivity
            stimuli.right = ACE_Processing_Realtime(audio_right, bufferHistory_right, app.p.Right);
            
            a=265;
            %for (i=1:p.Right.pulses_per_frame)
            for (i=1:numel(stimuli.right.electrodes))
                % Right Electrodes
                outputBuffer(a) =uint8(stimuli.right.electrodes(i)); a=a+1; 
            end
            a=391;
            for (i=1:numel(stimuli.right.electrodes))
                %for (i=1:p.Right.pulses_per_frame)
                % Right Amplitudes
                outputBuffer(a) = uint8(stimuli.right.current_levels(i)); a=a+1; 
            end
            bufferHistory_right = audio_right(app.p.Right.block_size-app.p.Right.NHIST+1:end);
        else
            %a=391;
            % Zero out right amplitudes
            outputBuffer(391:506) = uint8(0); 
        end
        % Write the BTE processed stimuli to the coil
        Write(s, outputBuffer,516); 
        
% ------------------------------------------------------------------------------ %        
        % USE FOLLOWING COMMENTED SECTION ONLY FOR TESTING WITH OSCILLOSCOPE
        % Comment the above line: Write(s, outputBuffer,516);
        % and uncomment the following section
        % This will write sine stimuli to the RF coil which can be
        % checked on the oscilloscope for timing verification.

%             for j=1:p.Left.pulses_per_frame/p.Left.Nmaxima;
%                 stim.l(j)= sine_token_l(indL);
%                 indL=indL+1;
%                 if indL==length(sine_token_l)+1
%                     indL=1;
%                 end
%             end
%             
%             for j=1:p.Right.pulses_per_frame/p.Right.Nmaxima;
%                 stim.r(j)= 200; %sine_token_l(indL);
%                 indR=indR+1;
%                 if indR==length(sine_token_r)+1
%                     indR=1;
%                 end
%             end
%             stimulus = UART_output_buffer(stim, p);
%             Write(s, stimulus,516);
% ------------------------------------------------------------------------------ % 

        clear AD_data_bytes; clear AD_data;
    end
    % Get the value of the stop button to quit while loop
    
end % end while loop, until stop button is pressed

% Clear the buffer variable
delete(s); clear s;

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
% Update the stop button
app.p.stop = 1;
        
% Turn the lamp off
app.StreamingLamp.Interruptible = 'on';
app.StreamingLamp.Color   = [0.81,0.02,0.02];         % red
app.LampStatusText.Text   = ['CCi-MOBILE: Inactive'];
app.StartButton.Enable    = 'on';
app.StopButton.Enable     = 'off';
pause(0.0001)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RealTimeStimulatorApp = uifigure('Visible', 'off');
            app.RealTimeStimulatorApp.Name = 'UI Figure';
            app.Header = uiimage(app.RealTimeStimulatorApp);
            app.LeftPanel = uipanel(app.RealTimeStimulatorApp);
            app.LeftPanel.Title = 'Left';
            app.ActiveSwitch_L = uiswitch(app.LeftPanel, 'slider');
            app.ActiveSwitch_L.Items = {'Active', 'Inactive'};
            app.ActiveSwitch_L.Value = 'Active';
            app.slider_sensitivity_left = uislider(app.LeftPanel);
            app.slider_sensitivity_left.Limits = [0 50];
            app.slider_sensitivity_left.MajorTicks = [];
            app.slider_sensitivity_left.MajorTickLabels = {''};
            app.slider_sensitivity_left.ValueChangedFcn = createCallbackFcn(app, @slider_sensitivity_leftValueChanged, true);
            app.slider_sensitivity_left.MinorTicks = [];
            app.slider_sensitivity_left.Value = 25;
            app.slider_gain_left = uislider(app.LeftPanel);
            app.slider_gain_left.Limits = [0 50];
            app.slider_gain_left.MajorTicks = [];
            app.slider_gain_left.ValueChangedFcn = createCallbackFcn(app, @slider_gain_leftValueChanged, true);
            app.slider_gain_left.MinorTicks = [];
            app.slider_gain_left.Value = 25;
            app.slider_volume_left = uislider(app.LeftPanel);
            app.slider_volume_left.Limits = [0 10];
            app.slider_volume_left.MajorTicks = [];
            app.slider_volume_left.MajorTickLabels = {''};
            app.slider_volume_left.ValueChangedFcn = createCallbackFcn(app, @slider_volume_leftValueChanged, true);
            app.slider_volume_left.MinorTicks = [];
            app.slider_volume_left.Value = 5;
            app.text25dB_lgain = uilabel(app.LeftPanel);
            app.text25dB_lgain.Text = '25dB';
            app.text0dB_lgain = uilabel(app.LeftPanel);
            app.text0dB_lgain.Text = '0dB';
            app.text50dB_lgain = uilabel(app.LeftPanel);
            app.text50dB_lgain.Text = '50dB';
            app.text25dB_lsens = uilabel(app.LeftPanel);
            app.text25dB_lsens.Text = '25dB';
            app.text0dB_lsens = uilabel(app.LeftPanel);
            app.text0dB_lsens.Text = '0dB';
            app.text50dB_lsens = uilabel(app.LeftPanel);
            app.text50dB_lsens.Text = '50dB';
            app.text25dB_lvol = uilabel(app.LeftPanel);
            app.text25dB_lvol.Text = '25dB';
            app.text0dB_lvol = uilabel(app.LeftPanel);
            app.text0dB_lvol.Text = '0dB';
            app.text50dB_lvol = uilabel(app.LeftPanel);
            app.text50dB_lvol.Text = '50dB';
            app.LsensitivityText = uilabel(app.LeftPanel);
            app.LsensitivityText.Text = 'Sensitivity = ';
            app.LgainText = uilabel(app.LeftPanel);
            app.LgainText.Text = 'Gain (dB) =';
            app.LvolumeText = uilabel(app.LeftPanel);
            app.LvolumeText.Text = 'Volume =';
            app.RightPanel = uipanel(app.RealTimeStimulatorApp);
            app.RightPanel.Title = 'Right';
            app.ActiveSwitch_R = uiswitch(app.RightPanel, 'slider');
            app.ActiveSwitch_R.Items = {'Active', 'Inactive'};
            app.ActiveSwitch_R.Value = 'Active';
            app.slider_sensitivity_right = uislider(app.RightPanel);
            app.slider_sensitivity_right.Limits = [0 50];
            app.slider_sensitivity_right.MajorTicks = [];
            app.slider_sensitivity_right.MajorTickLabels = {''};
            app.slider_sensitivity_right.ValueChangedFcn = createCallbackFcn(app, @slider_sensitivity_rightValueChanged, true);
            app.slider_sensitivity_right.MinorTicks = [];
            app.slider_sensitivity_right.Value = 25;
            app.slider_gain_right = uislider(app.RightPanel);
            app.slider_gain_right.Limits = [0 50];
            app.slider_gain_right.MajorTicks = [];
            app.slider_gain_right.ValueChangedFcn = createCallbackFcn(app, @slider_gain_rightValueChanged, true);
            app.slider_gain_right.MinorTicks = [];
            app.slider_gain_right.Value = 25;
            app.slider_volume_right = uislider(app.RightPanel);
            app.slider_volume_right.Limits = [0 10];
            app.slider_volume_right.MajorTicks = [];
            app.slider_volume_right.MajorTickLabels = {''};
            app.slider_volume_right.ValueChangedFcn = createCallbackFcn(app, @slider_volume_rightValueChanged, true);
            app.slider_volume_right.MinorTicks = [];
            app.slider_volume_right.Value = 5;
            app.text25dB_rgain = uilabel(app.RightPanel);
            app.text25dB_rgain.Text = '25dB';
            app.text0dB_rgain = uilabel(app.RightPanel);
            app.text0dB_rgain.Text = '0dB';
            app.text50dB_rgain = uilabel(app.RightPanel);
            app.text50dB_rgain.Text = '50dB';
            app.text25dB_rsens = uilabel(app.RightPanel);
            app.text25dB_rsens.Text = '25dB';
            app.text0dB_rsens = uilabel(app.RightPanel);
            app.text0dB_rsens.Text = '0dB';
            app.text50dB_rsens = uilabel(app.RightPanel);
            app.text50dB_rsens.Text = '50dB';
            app.text25dB_rvol = uilabel(app.RightPanel);
            app.text25dB_rvol.Text = '25dB';
            app.text0dB_rvol = uilabel(app.RightPanel);
            app.text0dB_rvol.Text = '0dB';
            app.text50dB_rvol = uilabel(app.RightPanel);
            app.text50dB_rvol.Text = '50dB';
            app.RsensitivityText = uilabel(app.RightPanel);
            app.RsensitivityText.Text = 'Sensitivity =';
            app.RgainText = uilabel(app.RightPanel);
            app.RgainText.Text = 'Gain (dB) =';
            app.RvolumeText = uilabel(app.RightPanel);
            app.RvolumeText.Text = 'Volume =';
            app.StartButton = uibutton(app.RealTimeStimulatorApp, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RealTimeStimulatorApp, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CCiMOBILEActivityPanel = uipanel(app.RealTimeStimulatorApp);
            app.CCiMOBILEActivityPanel.Title = 'CCi-MOBILE Activity';
            app.Label = uilabel(app.CCiMOBILEActivityPanel);
            app.Label.Text = '';
            app.StreamingLamp = uilamp(app.CCiMOBILEActivityPanel);
            app.LampDescription = uilabel(app.CCiMOBILEActivityPanel);
            app.LampDescription.Text = {'STREAMING STATUS'; 'Green: Streaming to CCi-MOBILE (real-time)'; 'Red: NOT Streaming to CCi-MOBILE (inactive)'};
            app.LampStatusText = uilabel(app.CCiMOBILEActivityPanel);
            app.LampStatusText.Text = 'CCi-MOBILE: Inactive';
            % Show the figure after all components are created
            app.RealTimeStimulatorApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RealtimeStimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RealTimeStimulatorApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RealTimeStimulatorApp)
        end
    end
end