classdef SpeechEnhancement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MamunsGUI                     matlab.ui.Figure
        Header                        matlab.ui.control.Image
        InputPanel                    matlab.ui.container.Panel
        LoadSelectionButton           matlab.ui.control.Button
        OrLabel                       matlab.ui.control.Label
        LoadSignalListbox             matlab.ui.control.ListBox
        LoadCustomSelectionButton     matlab.ui.control.Button
        GeneratePlotButton            matlab.ui.control.Button
        ORGSpectrogram                matlab.ui.control.UIAxes
        ENHSpectrogram                matlab.ui.control.UIAxes
        ORGWaveform                   matlab.ui.control.UIAxes
        ENHWaveform                   matlab.ui.control.UIAxes
        ORGElectrodogram              matlab.ui.control.UIAxes
        ENHElectrodogram              matlab.ui.control.UIAxes
        ORGLabel                      matlab.ui.control.Label
        ENHLabel                      matlab.ui.control.Label
        ResetCheckBox                 matlab.ui.control.CheckBox
        ImagePanel                    matlab.ui.container.Panel
        SpectrogramCheckBox           matlab.ui.control.CheckBox
        WaveformCheckBox              matlab.ui.control.CheckBox
        ElectrodogramCheckBox         matlab.ui.control.CheckBox
        PlayORGButton                 matlab.ui.control.Button
        PlayNOISESampleButton         matlab.ui.control.Button
        PlaySESIGButton               matlab.ui.control.Button
        PlayNOISYButton               matlab.ui.control.Button
        NoiseSelectButtonGroup        matlab.ui.container.ButtonGroup
        WGNButton                     matlab.ui.control.RadioButton
        SSNButton                     matlab.ui.control.RadioButton
        No3Button                     matlab.ui.control.RadioButton
        SpeechEnhancementButtonGroup  matlab.ui.container.ButtonGroup
        SpEnWeinerButton              matlab.ui.control.RadioButton
        SpEnNo2Button                 matlab.ui.control.RadioButton
        SpEnNo3Button                 matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
% Insert code when Generate Plot Button is Selected


% Assign value to 'GUI_Val'

app.GeneratePlotButton.Value = GUI_Val;
        end

        % Button pushed function: LoadSelectionButton
        function LoadSelectionButtonPushed(app, event)
% Insert code when Load Selection Button is pushed


% Assign value to 'GUI_Val'

app.LoadSelectionButton.Value = GUI_Val;
        end

        % Button pushed function: LoadCustomSelectionButton
        function LoadCustomSelectionButtonPushed(app, event)
            % Insert code when Load Custom Selection Button is pushed


% Assign value to 'GUI_Val'

app.LoadCustomSelectionButton.Value = GUI_Val;
        end

        % Selection changed function: NoiseSelectButtonGroup
        function NoiseSelectButtonGroupSelectionChanged(app, event)
selectedButton = app.NoiseSelectButtonGroup.SelectedObject;
% Insert code when Noise is selected


% Assign value to 'GUI_Val'

app.NoiseSelectButtonGroup.Value = GUI_Val;
        end

        % Selection changed function: SpeechEnhancementButtonGroup
        function SpeechEnhancementButtonGroupSelectionChanged(app, event)
selectedButton = app.SpeechEnhancementButtonGroup.SelectedObject;
% Insert code when Speech Enhancement option is selected


% Assign value to 'GUI_Val'

app.SpeechEnhancementButtonGroup.Value = GUI_Val;           
        end

        % Button pushed function: PlayORGButton
        function PlayORGButtonPushed(app, event)
% Insert code when Play Original Button is pushed


% Assign value to 'GUI_Val'

app.PlayORGButton.Value = GUI_Val;
        end

        % Button pushed function: PlayNOISESampleButton
        function PlayNOISESampleButtonPushed(app, event)
% Insert code when Play Noise Sample Button is pushed


% Assign value to 'GUI_Val'

app.PlayNOISESampleButton.Value = GUI_Val;
        end

        % Button pushed function: PlayNOISYButton
        function PlayNOISYButtonPushed(app, event)
% Insert code when Play NOISY Button is pushed


% Assign value to 'GUI_Val'

app.PlayNOISYButton.Value = GUI_Val;
        end

        % Button pushed function: PlaySESIGButton
        function PlaySESIGButtonPushed(app, event)
% Insert code when Play SESIG Button is pushed


% Assign value to 'GUI_Val'

app.PlaySESIGButton.Value = GUI_Val;
        end

        % Value changed function: SpectrogramCheckBox
        function SpectrogramCheckBoxValueChanged(app, event)
value = app.SpectrogramCheckBox.Value;
% Insert code when Spectrogram Check Box is Selected


% Assign value to 'GUI_Val'

value = GUI_Val;
        end

        % Value changed function: WaveformCheckBox
        function WaveformCheckBoxValueChanged(app, event)
value = app.WaveformCheckBox.Value;
% Insert code when Waveform Check Box is selected


% Assign value to 'GUI_Val'

value = GUI_Val;
        end

        % Value changed function: ElectrodogramCheckBox
        function ElectrodogramCheckBoxValueChanged(app, event)
value = app.ElectrodogramCheckBox.Value;
% Insert code when Waveform Check Box is selected


% Assign value to 'GUI_Val'

value = GUI_Val;  
        end

        % Value changed function: ResetCheckBox
        function ResetCheckBoxValueChanged(app, event)
            value = app.ResetCheckBox.Value;
% Insert code when Reset Check Box is selected


% Assign value to 'GUI_Val

value = GUI_Val;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MamunsGUI = uifigure('Visible', 'off');
            app.MamunsGUI.Name = 'UI Figure';
            app.Header = uiimage(app.MamunsGUI);
            app.Header.ImageSource = 'size70black.png';
            app.InputPanel = uipanel(app.MamunsGUI);
            app.InputPanel.Title = 'Input';
            app.LoadSelectionButton = uibutton(app.InputPanel, 'push');
            app.LoadSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectionButtonPushed, true);
            app.LoadSelectionButton.Text = 'Load Selection';
            app.OrLabel = uilabel(app.InputPanel);
            app.OrLabel.Text = 'Or';
            app.LoadSignalListbox = uilistbox(app.InputPanel);
            app.LoadSignalListbox.Items = {};
            app.LoadSignalListbox.Value = {};
            app.LoadCustomSelectionButton = uibutton(app.InputPanel, 'push');
            app.LoadCustomSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomSelectionButtonPushed, true);
            app.LoadCustomSelectionButton.Text = {'Load Custom'; ' Selection'};
            app.GeneratePlotButton = uibutton(app.MamunsGUI, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.ORGSpectrogram = uiaxes(app.MamunsGUI);
            title(app.ORGSpectrogram, '')
            xlabel(app.ORGSpectrogram, 'X')
            ylabel(app.ORGSpectrogram, 'Y')
            app.ENHSpectrogram = uiaxes(app.MamunsGUI);
            title(app.ENHSpectrogram, '')
            xlabel(app.ENHSpectrogram, 'X')
            ylabel(app.ENHSpectrogram, 'Y')
            app.ORGWaveform = uiaxes(app.MamunsGUI);
            title(app.ORGWaveform, '')
            xlabel(app.ORGWaveform, 'X')
            ylabel(app.ORGWaveform, 'Y')
            app.ENHWaveform = uiaxes(app.MamunsGUI);
            title(app.ENHWaveform, '')
            xlabel(app.ENHWaveform, 'X')
            ylabel(app.ENHWaveform, 'Y')
            app.ORGElectrodogram = uiaxes(app.MamunsGUI);
            title(app.ORGElectrodogram, '')
            xlabel(app.ORGElectrodogram, 'X')
            ylabel(app.ORGElectrodogram, 'Y')
            app.ENHElectrodogram = uiaxes(app.MamunsGUI);
            title(app.ENHElectrodogram, '')
            xlabel(app.ENHElectrodogram, 'X')
            ylabel(app.ENHElectrodogram, 'Y')
            app.ORGLabel = uilabel(app.MamunsGUI);
            app.ORGLabel.Text = 'ORG';
            app.ENHLabel = uilabel(app.MamunsGUI);
            app.ENHLabel.Text = 'ENH';
            app.ResetCheckBox = uicheckbox(app.MamunsGUI);
            app.ResetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetCheckBoxValueChanged, true);
            app.ResetCheckBox.Text = 'Reset (reselect file)';
            app.ImagePanel = uipanel(app.MamunsGUI);
            app.ImagePanel.Title = 'Image';
            app.SpectrogramCheckBox = uicheckbox(app.ImagePanel);
            app.SpectrogramCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpectrogramCheckBoxValueChanged, true);
            app.SpectrogramCheckBox.Text = 'Spectrogram';
            app.WaveformCheckBox = uicheckbox(app.ImagePanel);
            app.WaveformCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaveformCheckBoxValueChanged, true);
            app.WaveformCheckBox.Text = 'Waveform';
            app.ElectrodogramCheckBox = uicheckbox(app.ImagePanel);
            app.ElectrodogramCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElectrodogramCheckBoxValueChanged, true);
            app.ElectrodogramCheckBox.Text = 'Electrodogram';
            app.PlayORGButton = uibutton(app.MamunsGUI, 'push');
            app.PlayORGButton.ButtonPushedFcn = createCallbackFcn(app, @PlayORGButtonPushed, true);
            app.PlayORGButton.Text = 'Play ORG';
            app.PlayNOISESampleButton = uibutton(app.MamunsGUI, 'push');
            app.PlayNOISESampleButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNOISESampleButtonPushed, true);
            app.PlayNOISESampleButton.Text = {'Play NOISE '; 'Sample'};
            app.PlaySESIGButton = uibutton(app.MamunsGUI, 'push');
            app.PlaySESIGButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySESIGButtonPushed, true);
            app.PlaySESIGButton.Text = 'Play SESIG';
            app.PlayNOISYButton = uibutton(app.MamunsGUI, 'push');
            app.PlayNOISYButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNOISYButtonPushed, true);
            app.PlayNOISYButton.Text = 'Play NOISY';
            app.NoiseSelectButtonGroup = uibuttongroup(app.MamunsGUI);
            app.NoiseSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoiseSelectButtonGroupSelectionChanged, true);
            app.NoiseSelectButtonGroup.Title = 'Noise Select';
            app.WGNButton = uiradiobutton(app.NoiseSelectButtonGroup);
            app.WGNButton.Text = 'WGN';
            app.WGNButton.Value = true;
            app.SSNButton = uiradiobutton(app.NoiseSelectButtonGroup);
            app.SSNButton.Text = 'SSN';
            app.No3Button = uiradiobutton(app.NoiseSelectButtonGroup);
            app.No3Button.Text = 'No. 3';
            app.SpeechEnhancementButtonGroup = uibuttongroup(app.MamunsGUI);
            app.SpeechEnhancementButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpeechEnhancementButtonGroupSelectionChanged, true);
            app.SpeechEnhancementButtonGroup.Title = 'Speech Enhancement';
            app.SpEnWeinerButton = uiradiobutton(app.SpeechEnhancementButtonGroup);
            app.SpEnWeinerButton.Text = 'Weiner';
            app.SpEnWeinerButton.Value = true;
            app.SpEnNo2Button = uiradiobutton(app.SpeechEnhancementButtonGroup);
            app.SpEnNo2Button.Text = 'No.2';
            app.SpEnNo3Button = uiradiobutton(app.SpeechEnhancementButtonGroup);
            app.SpEnNo3Button.Text = 'No.3';
            % Show the figure after all components are created
            app.MamunsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpeechEnhancement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MamunsGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MamunsGUI)
        end
    end
end